classdef optic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        Tab                  matlab.ui.container.Tab
        Image7               matlab.ui.control.Image
        TextArea             matlab.ui.control.TextArea
        Label_1              matlab.ui.control.Label
        Button_6             matlab.ui.control.Button
        Button_5             matlab.ui.control.Button
        Button_4             matlab.ui.control.Button
        Button_3             matlab.ui.control.Button
        Button_1             matlab.ui.control.Button
        Image                matlab.ui.control.Image
        Tab_1                matlab.ui.container.Tab
        Image3               matlab.ui.control.Image
        Image2               matlab.ui.control.Image
        nmSpinner            matlab.ui.control.Spinner
        nmSpinnerLabel       matlab.ui.control.Label
        Button1_2            matlab.ui.control.Button
        e4mSlider            matlab.ui.control.Slider
        Label_2              matlab.ui.control.Label
        Button1_1            matlab.ui.control.Button
        UIAxes1              matlab.ui.control.UIAxes
        Tab_3                matlab.ui.container.Tab
        TextArea_2           matlab.ui.control.TextArea
        Image7_2             matlab.ui.control.Image
        Image3_2             matlab.ui.control.Image
        Button3_3            matlab.ui.control.Button
        nmSpinner3_3         matlab.ui.control.Spinner
        nmSpinner_2Label_4   matlab.ui.control.Label
        e4mSlider3           matlab.ui.control.Slider
        e4mLabel_2           matlab.ui.control.Label
        nmSpinner3_2         matlab.ui.control.Spinner
        nmSpinner_2Label_3   matlab.ui.control.Label
        nmSpinner3_1         matlab.ui.control.Spinner
        nmSpinner_2Label_2   matlab.ui.control.Label
        Button3_2            matlab.ui.control.Button
        Button3_1            matlab.ui.control.Button
        Image2_2             matlab.ui.control.Image
        UIAxes3_1            matlab.ui.control.UIAxes
        UIAxes3_2            matlab.ui.control.UIAxes
        Tab_2                matlab.ui.container.Tab
        Image3_3             matlab.ui.control.Image
        Image2_3             matlab.ui.control.Image
        nmSpinner2           matlab.ui.control.Spinner
        nmSpinner_2Label     matlab.ui.control.Label
        e4mSlider2           matlab.ui.control.Slider
        e4mLabel             matlab.ui.control.Label
        Button2_2            matlab.ui.control.Button
        Button2_1            matlab.ui.control.Button
        UIAxes2              matlab.ui.control.UIAxes
        Tab_5                matlab.ui.container.Tab
        Label_3              matlab.ui.control.Label
        Button_10            matlab.ui.control.Button
        Button_9             matlab.ui.control.Button
        Button_8             matlab.ui.control.Button
        Button_7             matlab.ui.control.Button
        Image_2              matlab.ui.control.Image
        Tab_4                matlab.ui.container.Tab
        TextArea_3           matlab.ui.control.TextArea
        Image7_3             matlab.ui.control.Image
        nmSpinner3_5         matlab.ui.control.Spinner
        nmSpinner_2Label_6   matlab.ui.control.Label
        nmSpinner3_4         matlab.ui.control.Spinner
        nmSpinner_2Label_5   matlab.ui.control.Label
        Button3_4            matlab.ui.control.Button
        Image3_4             matlab.ui.control.Image
        Image2_4             matlab.ui.control.Image
        Button_14            matlab.ui.control.Button
        Button_12            matlab.ui.control.Button
        Button2_3            matlab.ui.control.Button
        e4mLabel_3           matlab.ui.control.Label
        e4mSlider4_1         matlab.ui.control.Slider
        Button4_1            matlab.ui.control.Button
        UIAxes4              matlab.ui.control.UIAxes
        Tab_6                matlab.ui.container.Tab
        TextArea_4           matlab.ui.control.TextArea
        Image7_4             matlab.ui.control.Image
        nmSpinner3_7         matlab.ui.control.Spinner
        nmSpinner_2Label_8   matlab.ui.control.Label
        nmSpinner3_6         matlab.ui.control.Spinner
        nmSpinner_2Label_7   matlab.ui.control.Label
        Button3_5            matlab.ui.control.Button
        Image3_5             matlab.ui.control.Image
        Image2_5             matlab.ui.control.Image
        Button_15            matlab.ui.control.Button
        Button_11            matlab.ui.control.Button
        Button2_5            matlab.ui.control.Button
        Button1_3            matlab.ui.control.Button
        UIAxes4_2            matlab.ui.control.UIAxes
        Tab_7                matlab.ui.container.Tab
        TextArea_5           matlab.ui.control.TextArea
        Image7_5             matlab.ui.control.Image
        nmSpinner3_9         matlab.ui.control.Spinner
        nmSpinner_2Label_10  matlab.ui.control.Label
        nmSpinner3_8         matlab.ui.control.Spinner
        nmSpinner_2Label_9   matlab.ui.control.Label
        Button3_6            matlab.ui.control.Button
        Image3_6             matlab.ui.control.Image
        Image2_6             matlab.ui.control.Image
        Button_16            matlab.ui.control.Button
        Button_13            matlab.ui.control.Button
        Button2_4            matlab.ui.control.Button
        Button1_4            matlab.ui.control.Button
        UIAxes4_3            matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        E_Vortex; % Description
        lambda_Vortex;
    end
    
    methods (Access = private)
        
        function result = laguerre(app,p,l,x)
            result = 0;
            if p == 0
                result = 1;
            elseif p == 1
                result = 1+abs(l)-x;
            else
                result = (1/p)*((2*p+l-1-x).*laguerre(app,p-1,abs(l),x)-(p+l-1)*laguerre(app,p-2,abs(l),x));
            end
        end
        
    end
    
    methods (Access = public)
        
        function rgb = lambda2rgb(app,Wavelength)
            if (Wavelength >= 380 && Wavelength < 440)
                Red = -(Wavelength - 440) / (440 - 380);
                Green = 0.0;
                Blue = 1.0;
            elseif (Wavelength >= 440 && Wavelength < 490)
                Red = 0.0;
                Green = (Wavelength - 440) / (490 - 440);
                Blue = 1.0;
            elseif (Wavelength >= 490 && Wavelength < 510)
                Red = 0.0;
                Green = 1.0;
                Blue = -(Wavelength - 510) / (510 - 490);
            elseif (Wavelength >= 510 && Wavelength < 580)
                Red = (Wavelength - 510) / (580 - 510);
                Green = 1.0;
                Blue = 0.0;
            elseif (Wavelength >= 580 && Wavelength < 645)
                Red = 1.0;
                Green = -(Wavelength - 645) / (645 - 580);
                Blue = 0.0;
            elseif (Wavelength >= 645 && Wavelength < 781)
                Red = 1.0;
                Green = 0.0;
                Blue = 0.0;
            else
                Red = 0.0;
                Green = 0.0;
                Blue = 0.0;
            end
        
            % Let the intensity fall off near the vision limits
            if (Wavelength >= 380 && Wavelength < 420)
                factor = 0.3 + 0.7 * (Wavelength - 380) / (420 - 380);
            elseif (Wavelength >= 420 && Wavelength < 701)
                factor = 1.0;
            elseif (Wavelength >= 701 && Wavelength < 781)
                factor = 0.3 + 0.7 * (780 - Wavelength) / (780 - 700);
            else
                factor = 0.0;
            end
            
            rgb=[Red Green Blue];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % def=1e-5;
            % L=0.005;
            % [x1,y1]=meshgrid(-L:def:L);
            % I1=ones(size(x1,1),size(y1,1));
            % imshow(I1*0,'Parent',app.UIAxes1)
            % 
            % ym=1e-3; 
            % [x2,y2]=meshgrid(-ym:ym/1000:ym);
            % I2=zeros(size(x2,1),size(y2,1));
            % I2(end,end)=1;
            % pcolor(x2,y2,I2,'Parent',app.UIAxes1_2)
            % shading(app.UIAxes2, 'interp'); % 使用插值来平滑颜色
            % colormap(app.UIAxes2,'gray')
        end

        % Callback function
        function ButtonPushed(app, event)
            app.TextArea.Value="Hello world!";
        end

        % Callback function
        function ButtonPushed2(app, event)
            app.TabGroup.SelectedTab=app.Tab_1;
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            selection = uiconfirm(app.UIFigure, '您确定要退出应用程序吗？', '退出确认', ...
            'Options', {'是', '否'}, 'DefaultOption', '否');
            if isequal(selection, '是')
                delete(app); % 关闭应用程序
            else
                % 如果用户选择"否"，则阻止应用程序关闭
                return;
            end
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            app.TabGroup.SelectedTab=app.Tab_1;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.TabGroup.SelectedTab=app.Tab_2;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.TabGroup.SelectedTab=app.Tab_3;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.TabGroup.SelectedTab=app.Tab_5;
        end

        % Button pushed function: Button1_1
        function Button1_1Pushed(app, event)
            app.TabGroup.SelectedTab=app.Tab;
        end

        % Button pushed function: Button2_1
        function Button2_1Pushed(app, event)
            app.TabGroup.SelectedTab=app.Tab;
        end

        % Button pushed function: Button3_1
        function Button3_1Pushed(app, event)
            app.TabGroup.SelectedTab=app.Tab;
        end

        % Button pushed function: Button4_1
        function Button4_1Pushed(app, event)
            app.TabGroup.SelectedTab=app.Tab;
        end

        % Button pushed function: Button1_2
        function Button1_2Pushed(app, event)
            lambda=app.nmSpinner.Value*1e-9;       %波长
            r=app.e4mSlider.Value*1e-4;       %圆孔半径
            def=1e-5;
            N=800;
            L=0.002;
            a=linspace(-L,L,N);
            [x,y]=meshgrid(a);
            k=sqrt(x.^2+y.^2);
            s=2*pi*r*k./lambda;
            I=4*(besselj(1,s)./(s+eps)).^2;
            I=I/max(max(I));
            for i=1:N
                for j=1:N
                    if (I(i,j)<0.01)
                        I(i,j)=I(i,j)*30;
                    end
                end
            end
            % imshow(I*255,'Parent',app.UIAxes1)
            x=x*1000;
            y=y*1000;
            pcolor(app.UIAxes1,x,y,I)
            shading(app.UIAxes1, 'interp'); % 使用插值来平滑颜色
            colormap(app.UIAxes1,'gray')
        end

        % Button pushed function: Button2_2
        function Button2_2Pushed(app, event)
            lambda=app.nmSpinner2.Value*(1e-9);    %波长500nm
            d = app.e4mSlider2.Value*1e-3;   %%双缝间距5mm
            D=1;    %%双缝距离观察屏之间的距离1m
            ym=1e-3;    %%表示最宽的位置为5个亮纹处
            [x,y]=meshgrid(-ym:ym/200:ym);
            d1=sqrt((x+d/2).^2+D^2);
            d2=sqrt((x-d/2).^2+D^2);
            delta=2*pi*(d1-d2)/lambda;
            I=4*cos(delta/2).^2;
            pcolor(app.UIAxes2,x,y,I)
            shading(app.UIAxes2, 'interp'); % 使用插值来平滑颜色
            colormap(app.UIAxes2,'gray')
        end

        % Button pushed function: Button3_2
        function Button3_2Pushed(app, event)
            N= 400;
            lambda = app.nmSpinner3_1.Value*(1e-9);    %波长为632nm
            app.lambda_Vortex=lambda;
            k = 2*pi/lambda;    %波数
            w0 = app.e4mSlider3.Value*(1e-3);          %光斑尺寸
            L=0.02;
            x = linspace(-L,L,N);     
            y = x;
            [X,Y] = meshgrid(x,y);
            [theta,r] = cart2pol(X,Y);
            Z_R = pi*w0^2/lambda;      %瑞利长度
            z = 0;
            w_z = w0*sqrt(1+(z/Z_R)^2);%光束在z位置的半径
            m=app.nmSpinner3_2.Value;
            p = app.nmSpinner3_3.Value;      %p = 0, 1, 2...;
            E3 = sqrt(2*factorial(p)/pi/(p+factorial(abs(m))))*(1/w_z)*(sqrt(2)*r/w_z).^abs(m)...                 .*exp(-r.^2/w_z^2).*laguerre(app,p,abs(m),2*r.^2/w_z^2).*exp(-1i*m*theta).*exp(-1i*k*z)...                 .*exp(-1i*k*r.^2*z/2/(z^2+Z_R^2))*exp(-1i*(2*p+abs(m)+1)*atan(z/Z_R));
                .*exp(-r.^2/w_z^2).*laguerre(app,p,abs(m),2*r.^2/w_z^2).*exp(-1i*m*theta).*exp(-1i*k*z)...
                .*exp(-1i*k*r.^2*z/2/(z^2+Z_R^2))*exp(-1i*(2*p+abs(m)+1)*atan(z/Z_R));
            E3=conj(E3);
            app.E_Vortex=E3;
            I3 = E3.*conj(E3);  
            I3 = I3/max(max(I3));
            X=X*100;
            Y=Y*100;
            mesh(X,Y,I3,'Parent',app.UIAxes3_1)           %三维
            colorbar(app.UIAxes3_1);
            phase = angle(E3);
            % 绘制相位图
            pcolor(X,Y,phase,'Parent',app.UIAxes3_2);
            shading(app.UIAxes3_2,'interp')
            colorbar(app.UIAxes3_2);
        end

        % Button pushed function: Button2_3
        function Button2_3Pushed(app, event)
            lambda = app.lambda_Vortex;
            d = app.e4mSlider4_1.Value*1e-3;   %%双缝间距5mm
            D=30;    %%双缝距离观察屏之间的距离1m
            x1 = -d/2;
            x2 = d/2;
            L=0.02;
            N=400;
            x = linspace(-L,L,N);     
            y = x;
            for i=1:400
                for j=1:400
                    E1(i,j)=app.E_Vortex(floor(x1/0.02*200+200),i)*exp(1i*sqrt((x(j)-x1)^2+D^2)/lambda*2*pi);
                    E2(i,j)=app.E_Vortex(floor(x2/0.02*200+200),i)*exp(1i*sqrt((x(j)-x2)^2+D^2)/lambda*2*pi);
                end
            end
            E3=E1+E2;
            I= E3.*conj(E3);  
            I=I/max(max(I));
            [X,Y] = meshgrid(x,y);
            X=X*100;
            Y=Y*100;
            pcolor(app.UIAxes4,X,Y,I)
            shading(app.UIAxes4, 'interp'); % 使用插值来平滑颜色
            colormap(app.UIAxes4,'gray')
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
             app.TabGroup.SelectedTab=app.Tab_4;
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            app.TabGroup.SelectedTab=app.Tab;
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.TabGroup.SelectedTab=app.Tab_6;
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.TabGroup.SelectedTab=app.Tab_7;
        end

        % Button pushed function: Button1_3
        function Button1_3Pushed(app, event)
            app.TabGroup.SelectedTab=app.Tab;
        end

        % Button pushed function: Button1_4
        function Button1_4Pushed(app, event)
            app.TabGroup.SelectedTab=app.Tab;
        end

        % Button pushed function: Button2_5
        function Button2_5Pushed(app, event)
            lambda = app.lambda_Vortex;
            L=0.02;
            N=400;
            x = linspace(-L,L,N);     
            y = x;
            E1=app.E_Vortex;
            E1=E1/max(max(E1));
            % e0=0;
            % for i=1:400
            %     for j=1:400
            %         e0=e0+abs(E1(i,j));
            %     end
            % end
            % e0=e0/400/400;
            % e0=1;
            [X,Y] = meshgrid(x,y);
            E2 = exp(-1i*(X-200)/400*0.02/lambda*2*pi/sind(10));      %平面波
            E3=E1+E2;
            I= E3.*conj(E3);  
            I=I/max(max(I));
            X=X*100;
            Y=Y*100;
            pcolor(app.UIAxes4_2,X,Y,I)
            shading(app.UIAxes4_2, 'interp'); % 使用插值来平滑颜色
            colormap(app.UIAxes4_2,'gray')
        end

        % Button pushed function: Button2_4
        function Button2_4Pushed(app, event)
            lambda = app.lambda_Vortex;
            L=0.02;
            N=400;
            x = linspace(-L,L,N);     
            y = x;
            E1=app.E_Vortex;
            E1=E1/max(max(E1));
            % e0=0;
            % for i=1:400
            %     for j=1:400
            %         e0=e0+abs(E1(i,j));
            %     end
            % end
            % e0=e0/400/400;
            % e0=1;
            [X,Y] = meshgrid(x,y);
            for i=1:400
               for j=1:400
                    E2(i,j) = exp(-1i*(x(i)^2+y(j)^2)/lambda*2*pi/30);      %球面波
               end
            end
            E3=E1+E2;
            I= E3.*conj(E3);  
            I=I/max(max(I));
            X=X*100;
            Y=Y*100;
            pcolor(app.UIAxes4_3,X,Y,I)
            shading(app.UIAxes4_3, 'interp'); % 使用插值来平滑颜色
            colormap(app.UIAxes4_3,'gray')
        end

        % Callback function
        function Button2_6Pushed(app, event)
            app.TabGroup.SelectedTab=app.Tab;
        end

        % Callback function
        function Button2_7Pushed(app, event)
            lambda=app.nmSpinner2_2.Value*(1e-9);    %波长500nm
            d = app.e4mSlider2_2.Value*1e-3;   %%双缝间距5mm
            D=1;    %%双缝距离观察屏之间的距离1m
            ym=1e-3;    %%表示最宽的位置为5个亮纹处
            [x,y]=meshgrid(-ym:ym/200:ym);
            d1=sqrt((x+d/2).^2+D^2);
            d2=sqrt((x-d/2).^2+D^2);
            delta=2*pi*(d1-d2)/lambda;
            I=4*cos(delta/2).^2;
            pcolor(app.UIAxes1_2,x,y,I)
            shading(app.UIAxes1_2, 'interp'); % 使用插值来平滑颜色
            colormap(app.UIAxes1_2,'gray')
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            app.TabGroup.SelectedTab=app.Tab_5;
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            app.TabGroup.SelectedTab=app.Tab_5;
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            app.TabGroup.SelectedTab=app.Tab_5;
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            app.TabGroup.SelectedTab=app.Tab_3;
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            app.TabGroup.SelectedTab=app.Tab_3;
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            app.TabGroup.SelectedTab=app.Tab_3;
        end

        % Button pushed function: Button3_3
        function Button3_3Pushed(app, event)
            app.TabGroup.SelectedTab=app.Tab_5;
        end

        % Button pushed function: Button3_4
        function Button3_4Pushed(app, event)
            N= 400;
            lambda = app.nmSpinner3_1.Value*(1e-9);    %波长为632nm
            app.lambda_Vortex=lambda;
            k = 2*pi/lambda;    %波数
            w0 = app.e4mSlider3.Value*(1e-3);          %光斑尺寸
            L=0.02;
            x = linspace(-L,L,N);     
            y = x;
            [X,Y] = meshgrid(x,y);
            [theta,r] = cart2pol(X,Y);
            Z_R = pi*w0^2/lambda;      %瑞利长度
            z = 0;
            w_z = w0*sqrt(1+(z/Z_R)^2);%光束在z位置的半径
            m=app.nmSpinner3_4.Value;
            p = app.nmSpinner3_5.Value;      %p = 0, 1, 2...;
            E3 = sqrt(2*factorial(p)/pi/(p+factorial(abs(m))))*(1/w_z)*(sqrt(2)*r/w_z).^abs(m)...
                .*exp(-r.^2/w_z^2).*laguerre(app,p,abs(m),2*r.^2/w_z^2).*exp(-1i*m*theta).*exp(-1i*k*z)...
                .*exp(-1i*k*r.^2*z/2/(z^2+Z_R^2))*exp(-1i*(2*p+abs(m)+1)*atan(z/Z_R));
            E3=conj(E3);
            app.E_Vortex=E3;
            I3 = E3.*conj(E3);  
            I3 = I3/max(max(I3));
            X=X*100;
            Y=Y*100;
            mesh(X,Y,I3,'Parent',app.UIAxes3_1)           %三维
            colorbar(app.UIAxes3_1);
            phase = angle(E3);
            % 绘制相位图
            pcolor(X,Y,phase,'Parent',app.UIAxes3_2);
            shading(app.UIAxes3_2,'interp')
            colorbar(app.UIAxes3_2);
        end

        % Button pushed function: Button3_5
        function Button3_5Pushed(app, event)
            N= 400;
            lambda = app.nmSpinner3_1.Value*(1e-9);    %波长为632nm
            app.lambda_Vortex=lambda;
            k = 2*pi/lambda;    %波数
            w0 = app.e4mSlider3.Value*(1e-3);          %光斑尺寸
            L=0.02;
            x = linspace(-L,L,N);     
            y = x;
            [X,Y] = meshgrid(x,y);
            [theta,r] = cart2pol(X,Y);
            Z_R = pi*w0^2/lambda;      %瑞利长度
            z = 0;
            w_z = w0*sqrt(1+(z/Z_R)^2);%光束在z位置的半径
            m=app.nmSpinner3_6.Value;
            p = app.nmSpinner3_7.Value;      %p = 0, 1, 2...;
            E3 = sqrt(2*factorial(p)/pi/(p+factorial(abs(m))))*(1/w_z)*(sqrt(2)*r/w_z).^abs(m)...
                .*exp(-r.^2/w_z^2).*laguerre(app,p,abs(m),2*r.^2/w_z^2).*exp(-1i*m*theta).*exp(-1i*k*z)...
                .*exp(-1i*k*r.^2*z/2/(z^2+Z_R^2))*exp(-1i*(2*p+abs(m)+1)*atan(z/Z_R));
            E3=conj(E3);
            app.E_Vortex=E3;
            I3 = E3.*conj(E3);  
            I3 = I3/max(max(I3));
            X=X*100;
            Y=Y*100;
            mesh(X,Y,I3,'Parent',app.UIAxes3_1)           %三维
            colorbar(app.UIAxes3_1);
            phase = angle(E3);
            % 绘制相位图
            pcolor(X,Y,phase,'Parent',app.UIAxes3_2);
            shading(app.UIAxes3_2,'interp')
            colorbar(app.UIAxes3_2);
        end

        % Button pushed function: Button3_6
        function Button3_6Pushed(app, event)
            N= 400;
            lambda = app.nmSpinner3_1.Value*(1e-9);    %波长为632nm
            app.lambda_Vortex=lambda;
            k = 2*pi/lambda;    %波数
            w0 = app.e4mSlider3.Value*(1e-3);          %光斑尺寸
            L=0.02;
            x = linspace(-L,L,N);     
            y = x;
            [X,Y] = meshgrid(x,y);
            [theta,r] = cart2pol(X,Y);
            Z_R = pi*w0^2/lambda;      %瑞利长度
            z = 0;
            w_z = w0*sqrt(1+(z/Z_R)^2);%光束在z位置的半径
            m=app.nmSpinner3_8.Value;
            p = app.nmSpinner3_9.Value;      %p = 0, 1, 2...;
            E3 = sqrt(2*factorial(p)/pi/(p+factorial(abs(m))))*(1/w_z)*(sqrt(2)*r/w_z).^abs(m)...
                .*exp(-r.^2/w_z^2).*laguerre(app,p,abs(m),2*r.^2/w_z^2).*exp(-1i*m*theta).*exp(-1i*k*z)...
                .*exp(-1i*k*r.^2*z/2/(z^2+Z_R^2))*exp(-1i*(2*p+abs(m)+1)*atan(z/Z_R));
            E3=conj(E3);
            app.E_Vortex=E3;
            I3 = E3.*conj(E3);  
            I3 = I3/max(max(I3));
            X=X*100;
            Y=Y*100;
            mesh(X,Y,I3,'Parent',app.UIAxes3_1)           %三维
            colorbar(app.UIAxes3_1);
            phase = angle(E3);
            % 绘制相位图
            pcolor(X,Y,phase,'Parent',app.UIAxes3_2);
            shading(app.UIAxes3_2,'interp')
            colorbar(app.UIAxes3_2);
        end

        % Value changed function: nmSpinner
        function nmSpinnerValueChanged(app, event)
            lambda=app.nmSpinner.Value*1e-9;       %波长
            r=app.e4mSlider.Value*1e-4;       %圆孔半径
            def=1e-5;
            N=800;
            L=0.002;
            a=linspace(-L,L,N);
            [x,y]=meshgrid(a);
            k=sqrt(x.^2+y.^2);
            s=2*pi*r*k./lambda;
            I=4*(besselj(1,s)./(s+eps)).^2;
            I=I/max(max(I));
            for i=1:N
                for j=1:N
                    if (I(i,j)<0.01)
                        I(i,j)=I(i,j)*30;
                    end
                end
            end
            % imshow(I*255,'Parent',app.UIAxes1)
            x=x*1000;
            y=y*1000;
            imagesc(app.UIAxes1,x,y,I)
            shading(app.UIAxes1, 'interp'); % 使用插值来平滑颜色
            rgb=lambda2rgb(app,lambda);
            colormap0 = [linspace(0, rgb(1), 256)', linspace(0, rgb(2), 256)', linspace(0, rgb(3), 256)'];
            colormap(app.UIAxes1,colormap0)
            
        end

        % Value changed function: e4mSlider
        function e4mSliderValueChanged(app, event)
            lambda=app.nmSpinner.Value*1e-9;       %波长
            r=app.e4mSlider.Value*1e-4;       %圆孔半径
            def=1e-5;
            N=800;
            L=0.002;
            a=linspace(-L,L,N);
            [x,y]=meshgrid(a);
            k=sqrt(x.^2+y.^2);
            s=2*pi*r*k./lambda;
            I=4*(besselj(1,s)./(s+eps)).^2;
            I=I/max(max(I));
            for i=1:N
                for j=1:N
                    if (I(i,j)<0.01)
                        I(i,j)=I(i,j)*30;
                    end
                end
            end
            % imshow(I*255,'Parent',app.UIAxes1)
            x=x*1000;
            y=y*1000;
            pcolor(app.UIAxes1,x,y,I)
            shading(app.UIAxes1, 'interp'); % 使用插值来平滑颜色
            colormap(app.UIAxes1,'gray')
        end

        % Value changed function: nmSpinner2
        function nmSpinner2ValueChanged(app, event)
            lambda=app.nmSpinner2.Value*(1e-9);    %波长500nm
            d = app.e4mSlider2.Value*1e-3;   %%双缝间距5mm
            D=1;    %%双缝距离观察屏之间的距离1m
            ym=1e-3;    %%表示最宽的位置为5个亮纹处
            [x,y]=meshgrid(-ym:ym/200:ym);
            d1=sqrt((x+d/2).^2+D^2);
            d2=sqrt((x-d/2).^2+D^2);
            delta=2*pi*(d1-d2)/lambda;
            I=4*cos(delta/2).^2;
            pcolor(app.UIAxes2,x,y,I)
            shading(app.UIAxes2, 'interp'); % 使用插值来平滑颜色
            colormap(app.UIAxes2,'gray')
        end

        % Value changed function: e4mSlider2
        function e4mSlider2ValueChanged(app, event)
            lambda=app.nmSpinner2.Value*(1e-9);    %波长500nm
            d = app.e4mSlider2.Value*1e-3;   %%双缝间距5mm
            D=1;    %%双缝距离观察屏之间的距离1m
            ym=1e-3;    %%表示最宽的位置为5个亮纹处
            [x,y]=meshgrid(-ym:ym/200:ym);
            d1=sqrt((x+d/2).^2+D^2);
            d2=sqrt((x-d/2).^2+D^2);
            delta=2*pi*(d1-d2)/lambda;
            I=4*cos(delta/2).^2;
            pcolor(app.UIAxes2,x,y,I)
            shading(app.UIAxes2, 'interp'); % 使用插值来平滑颜色
            colormap(app.UIAxes2,'gray')
        end

        % Value changed function: nmSpinner3_1
        function nmSpinner3_1ValueChanged(app, event)
            N= 400;
            lambda = app.nmSpinner3_1.Value*(1e-9);    %波长为632nm
            app.lambda_Vortex=lambda;
            k = 2*pi/lambda;    %波数
            w0 = app.e4mSlider3.Value*(1e-3);          %光斑尺寸
            L=0.02;
            x = linspace(-L,L,N);     
            y = x;
            [X,Y] = meshgrid(x,y);
            [theta,r] = cart2pol(X,Y);
            Z_R = pi*w0^2/lambda;      %瑞利长度
            z = 0;
            w_z = w0*sqrt(1+(z/Z_R)^2);%光束在z位置的半径
            m=app.nmSpinner3_2.Value;
            p = app.nmSpinner3_3.Value;      %p = 0, 1, 2...;
            E3 = sqrt(2*factorial(p)/pi/(p+factorial(abs(m))))*(1/w_z)*(sqrt(2)*r/w_z).^abs(m)...
                .*exp(-r.^2/w_z^2).*laguerre(app,p,abs(m),2*r.^2/w_z^2).*exp(-1i*m*theta).*exp(-1i*k*z)...
                .*exp(-1i*k*r.^2*z/2/(z^2+Z_R^2))*exp(-1i*(2*p+abs(m)+1)*atan(z/Z_R));
            E3=conj(E3);
            app.E_Vortex=E3;
            I3 = E3.*conj(E3);  
            I3 = I3/max(max(I3));
            X=X*100;
            Y=Y*100;
            mesh(X,Y,I3,'Parent',app.UIAxes3_1)           %三维
            colorbar(app.UIAxes3_1);
            phase = angle(E3);
            % 绘制相位图
            pcolor(X,Y,phase,'Parent',app.UIAxes3_2);
            shading(app.UIAxes3_2,'interp')
            colorbar(app.UIAxes3_2);
        end

        % Value changed function: nmSpinner3_2
        function nmSpinner3_2ValueChanged(app, event)
            N= 400;
            lambda = app.nmSpinner3_1.Value*(1e-9);    %波长为632nm
            app.lambda_Vortex=lambda;
            k = 2*pi/lambda;    %波数
            w0 = app.e4mSlider3.Value*(1e-3);          %光斑尺寸
            L=0.02;
            x = linspace(-L,L,N);     
            y = x;
            [X,Y] = meshgrid(x,y);
            [theta,r] = cart2pol(X,Y);
            Z_R = pi*w0^2/lambda;      %瑞利长度
            z = 0;
            w_z = w0*sqrt(1+(z/Z_R)^2);%光束在z位置的半径
            m=app.nmSpinner3_2.Value;
            p = app.nmSpinner3_3.Value;      %p = 0, 1, 2...;
            E3 = sqrt(2*factorial(p)/pi/(p+factorial(abs(m))))*(1/w_z)*(sqrt(2)*r/w_z).^abs(m)...
                .*exp(-r.^2/w_z^2).*laguerre(app,p,abs(m),2*r.^2/w_z^2).*exp(-1i*m*theta).*exp(-1i*k*z)...
                .*exp(-1i*k*r.^2*z/2/(z^2+Z_R^2))*exp(-1i*(2*p+abs(m)+1)*atan(z/Z_R));
            E3=conj(E3);
            app.E_Vortex=E3;
            I3 = E3.*conj(E3);  
            I3 = I3/max(max(I3));
            X=X*100;
            Y=Y*100;
            mesh(X,Y,I3,'Parent',app.UIAxes3_1)           %三维
            colorbar(app.UIAxes3_1);
            phase = angle(E3);
            % 绘制相位图
            pcolor(X,Y,phase,'Parent',app.UIAxes3_2);
            shading(app.UIAxes3_2,'interp')
            colorbar(app.UIAxes3_2);
        end

        % Value changed function: nmSpinner3_3
        function nmSpinner3_3ValueChanged(app, event)
            N= 400;
            lambda = app.nmSpinner3_1.Value*(1e-9);    %波长为632nm
            app.lambda_Vortex=lambda;
            k = 2*pi/lambda;    %波数
            w0 = app.e4mSlider3.Value*(1e-3);          %光斑尺寸
            L=0.02;
            x = linspace(-L,L,N);     
            y = x;
            [X,Y] = meshgrid(x,y);
            [theta,r] = cart2pol(X,Y);
            Z_R = pi*w0^2/lambda;      %瑞利长度
            z = 0;
            w_z = w0*sqrt(1+(z/Z_R)^2);%光束在z位置的半径
            m=app.nmSpinner3_2.Value;
            p = app.nmSpinner3_3.Value;      %p = 0, 1, 2...;
            E3 = sqrt(2*factorial(p)/pi/(p+factorial(abs(m))))*(1/w_z)*(sqrt(2)*r/w_z).^abs(m)...
                .*exp(-r.^2/w_z^2).*laguerre(app,p,abs(m),2*r.^2/w_z^2).*exp(-1i*m*theta).*exp(-1i*k*z)...
                .*exp(-1i*k*r.^2*z/2/(z^2+Z_R^2))*exp(-1i*(2*p+abs(m)+1)*atan(z/Z_R));
            E3=conj(E3);
            app.E_Vortex=E3;
            I3 = E3.*conj(E3);  
            I3 = I3/max(max(I3));
            X=X*100;
            Y=Y*100;
            mesh(X,Y,I3,'Parent',app.UIAxes3_1)           %三维
            colorbar(app.UIAxes3_1);
            phase = angle(E3);
            % 绘制相位图
            pcolor(X,Y,phase,'Parent',app.UIAxes3_2);
            shading(app.UIAxes3_2,'interp')
            colorbar(app.UIAxes3_2);
        end

        % Value changed function: e4mSlider3
        function e4mSlider3ValueChanged(app, event)
            N= 400;
            lambda = app.nmSpinner3_1.Value*(1e-9);    %波长为632nm
            app.lambda_Vortex=lambda;
            k = 2*pi/lambda;    %波数
            w0 = app.e4mSlider3.Value*(1e-3);          %光斑尺寸
            L=0.02;
            x = linspace(-L,L,N);     
            y = x;
            [X,Y] = meshgrid(x,y);
            [theta,r] = cart2pol(X,Y);
            Z_R = pi*w0^2/lambda;      %瑞利长度
            z = 0;
            w_z = w0*sqrt(1+(z/Z_R)^2);%光束在z位置的半径
            m=app.nmSpinner3_2.Value;
            p = app.nmSpinner3_3.Value;      %p = 0, 1, 2...;
            E3 = sqrt(2*factorial(p)/pi/(p+factorial(abs(m))))*(1/w_z)*(sqrt(2)*r/w_z).^abs(m)...
                .*exp(-r.^2/w_z^2).*laguerre(app,p,abs(m),2*r.^2/w_z^2).*exp(-1i*m*theta).*exp(-1i*k*z)...
                .*exp(-1i*k*r.^2*z/2/(z^2+Z_R^2))*exp(-1i*(2*p+abs(m)+1)*atan(z/Z_R));
            E3=conj(E3);
            app.E_Vortex=E3;
            I3 = E3.*conj(E3);  
            I3 = I3/max(max(I3));
            X=X*100;
            Y=Y*100;
            mesh(X,Y,I3,'Parent',app.UIAxes3_1)           %三维
            colorbar(app.UIAxes3_1);
            phase = angle(E3);
            % 绘制相位图
            pcolor(X,Y,phase,'Parent',app.UIAxes3_2);
            shading(app.UIAxes3_2,'interp')
            colorbar(app.UIAxes3_2);
        end

        % Value changed function: nmSpinner3_4
        function nmSpinner3_4ValueChanged(app, event)
            N= 400;
            lambda = app.nmSpinner3_1.Value*(1e-9);    %波长为632nm
            app.lambda_Vortex=lambda;
            k = 2*pi/lambda;    %波数
            w0 = app.e4mSlider3.Value*(1e-3);          %光斑尺寸
            L=0.02;
            x = linspace(-L,L,N);     
            y = x;
            [X,Y] = meshgrid(x,y);
            [theta,r] = cart2pol(X,Y);
            Z_R = pi*w0^2/lambda;      %瑞利长度
            z = 0;
            w_z = w0*sqrt(1+(z/Z_R)^2);%光束在z位置的半径
            m=app.nmSpinner3_4.Value;
            p = app.nmSpinner3_5.Value;      %p = 0, 1, 2...;
            E3 = sqrt(2*factorial(p)/pi/(p+factorial(abs(m))))*(1/w_z)*(sqrt(2)*r/w_z).^abs(m)...
                .*exp(-r.^2/w_z^2).*laguerre(app,p,abs(m),2*r.^2/w_z^2).*exp(-1i*m*theta).*exp(-1i*k*z)...
                .*exp(-1i*k*r.^2*z/2/(z^2+Z_R^2))*exp(-1i*(2*p+abs(m)+1)*atan(z/Z_R));
            E3=conj(E3);
            app.E_Vortex=E3;
            I3 = E3.*conj(E3);  
            I3 = I3/max(max(I3));
            X=X*100;
            Y=Y*100;
            mesh(X,Y,I3,'Parent',app.UIAxes3_1)           %三维
            colorbar(app.UIAxes3_1);
            phase = angle(E3);
            % 绘制相位图
            pcolor(X,Y,phase,'Parent',app.UIAxes3_2);
            shading(app.UIAxes3_2,'interp')
            colorbar(app.UIAxes3_2);
            
            lambda = app.lambda_Vortex;
            d = app.e4mSlider4_1.Value*1e-3;   %%双缝间距5mm
            D=30;    %%双缝距离观察屏之间的距离1m
            x1 = -d/2;
            x2 = d/2;
            L=0.02;
            N=400;
            x = linspace(-L,L,N);     
            y = x;
            for i=1:400
                for j=1:400
                    E1(i,j)=app.E_Vortex(floor(x1/0.02*200+200),i)*exp(1i*sqrt((x(j)-x1)^2+D^2)/lambda*2*pi);
                    E2(i,j)=app.E_Vortex(floor(x2/0.02*200+200),i)*exp(1i*sqrt((x(j)-x2)^2+D^2)/lambda*2*pi);
                end
            end
            E3=E1+E2;
            I= E3.*conj(E3);  
            I=I/max(max(I));
            [X,Y] = meshgrid(x,y);
            X=X*100;
            Y=Y*100;
            pcolor(app.UIAxes4,X,Y,I)
            shading(app.UIAxes4, 'interp'); % 使用插值来平滑颜色
            colormap(app.UIAxes4,'gray')
        end

        % Value changed function: nmSpinner3_5
        function nmSpinner3_5ValueChanged(app, event)
            N= 400;
            lambda = app.nmSpinner3_1.Value*(1e-9);    %波长为632nm
            app.lambda_Vortex=lambda;
            k = 2*pi/lambda;    %波数
            w0 = app.e4mSlider3.Value*(1e-3);          %光斑尺寸
            L=0.02;
            x = linspace(-L,L,N);     
            y = x;
            [X,Y] = meshgrid(x,y);
            [theta,r] = cart2pol(X,Y);
            Z_R = pi*w0^2/lambda;      %瑞利长度
            z = 0;
            w_z = w0*sqrt(1+(z/Z_R)^2);%光束在z位置的半径
            m=app.nmSpinner3_4.Value;
            p = app.nmSpinner3_5.Value;      %p = 0, 1, 2...;
            E3 = sqrt(2*factorial(p)/pi/(p+factorial(abs(m))))*(1/w_z)*(sqrt(2)*r/w_z).^abs(m)...
                .*exp(-r.^2/w_z^2).*laguerre(app,p,abs(m),2*r.^2/w_z^2).*exp(-1i*m*theta).*exp(-1i*k*z)...
                .*exp(-1i*k*r.^2*z/2/(z^2+Z_R^2))*exp(-1i*(2*p+abs(m)+1)*atan(z/Z_R));
            E3=conj(E3);
            app.E_Vortex=E3;
            I3 = E3.*conj(E3);  
            I3 = I3/max(max(I3));
            X=X*100;
            Y=Y*100;
            mesh(X,Y,I3,'Parent',app.UIAxes3_1)           %三维
            colorbar(app.UIAxes3_1);
            phase = angle(E3);
            % 绘制相位图
            pcolor(X,Y,phase,'Parent',app.UIAxes3_2);
            shading(app.UIAxes3_2,'interp')
            colorbar(app.UIAxes3_2);

            lambda = app.lambda_Vortex;
            d = app.e4mSlider4_1.Value*1e-3;   %%双缝间距5mm
            D=30;    %%双缝距离观察屏之间的距离1m
            x1 = -d/2;
            x2 = d/2;
            L=0.02;
            N=400;
            x = linspace(-L,L,N);     
            y = x;
            for i=1:400
                for j=1:400
                    E1(i,j)=app.E_Vortex(floor(x1/0.02*200+200),i)*exp(1i*sqrt((x(j)-x1)^2+D^2)/lambda*2*pi);
                    E2(i,j)=app.E_Vortex(floor(x2/0.02*200+200),i)*exp(1i*sqrt((x(j)-x2)^2+D^2)/lambda*2*pi);
                end
            end
            E3=E1+E2;
            I= E3.*conj(E3);  
            I=I/max(max(I));
            [X,Y] = meshgrid(x,y);
            X=X*100;
            Y=Y*100;
            pcolor(app.UIAxes4,X,Y,I)
            shading(app.UIAxes4, 'interp'); % 使用插值来平滑颜色
            colormap(app.UIAxes4,'gray')
        end

        % Value changed function: nmSpinner3_7
        function nmSpinner3_7ValueChanged(app, event)
            N= 400;
            lambda = app.nmSpinner3_1.Value*(1e-9);    %波长为632nm
            app.lambda_Vortex=lambda;
            k = 2*pi/lambda;    %波数
            w0 = app.e4mSlider3.Value*(1e-3);          %光斑尺寸
            L=0.02;
            x = linspace(-L,L,N);     
            y = x;
            [X,Y] = meshgrid(x,y);
            [theta,r] = cart2pol(X,Y);
            Z_R = pi*w0^2/lambda;      %瑞利长度
            z = 0;
            w_z = w0*sqrt(1+(z/Z_R)^2);%光束在z位置的半径
            m=app.nmSpinner3_6.Value;
            p = app.nmSpinner3_7.Value;      %p = 0, 1, 2...;
            E3 = sqrt(2*factorial(p)/pi/(p+factorial(abs(m))))*(1/w_z)*(sqrt(2)*r/w_z).^abs(m)...
                .*exp(-r.^2/w_z^2).*laguerre(app,p,abs(m),2*r.^2/w_z^2).*exp(-1i*m*theta).*exp(-1i*k*z)...
                .*exp(-1i*k*r.^2*z/2/(z^2+Z_R^2))*exp(-1i*(2*p+abs(m)+1)*atan(z/Z_R));
            E3=conj(E3);
            app.E_Vortex=E3;
            I3 = E3.*conj(E3);  
            I3 = I3/max(max(I3));
            X=X*100;
            Y=Y*100;
            mesh(X,Y,I3,'Parent',app.UIAxes3_1)           %三维
            colorbar(app.UIAxes3_1);
            phase = angle(E3);
            % 绘制相位图
            pcolor(X,Y,phase,'Parent',app.UIAxes3_2);
            shading(app.UIAxes3_2,'interp')
            colorbar(app.UIAxes3_2);

            lambda = app.lambda_Vortex;
            L=0.02;
            N=400;
            x = linspace(-L,L,N);     
            y = x;
            E1=app.E_Vortex;
            E1=E1/max(max(E1));
            % e0=0;
            % for i=1:400
            %     for j=1:400
            %         e0=e0+abs(E1(i,j));
            %     end
            % end
            % e0=e0/400/400;
            % e0=1;
            [X,Y] = meshgrid(x,y);
            E2 = exp(-1i*(X-200)/400*0.02/lambda*2*pi/sind(10));      %平面波
            E3=E1+E2;
            I= E3.*conj(E3);  
            I=I/max(max(I));
            X=X*100;
            Y=Y*100;
            pcolor(app.UIAxes4_2,X,Y,I)
            shading(app.UIAxes4_2, 'interp'); % 使用插值来平滑颜色
            colormap(app.UIAxes4_2,'gray')
        end

        % Value changed function: nmSpinner3_6
        function nmSpinner3_6ValueChanged(app, event)
            N= 400;
            lambda = app.nmSpinner3_1.Value*(1e-9);    %波长为632nm
            app.lambda_Vortex=lambda;
            k = 2*pi/lambda;    %波数
            w0 = app.e4mSlider3.Value*(1e-3);          %光斑尺寸
            L=0.02;
            x = linspace(-L,L,N);     
            y = x;
            [X,Y] = meshgrid(x,y);
            [theta,r] = cart2pol(X,Y);
            Z_R = pi*w0^2/lambda;      %瑞利长度
            z = 0;
            w_z = w0*sqrt(1+(z/Z_R)^2);%光束在z位置的半径
            m=app.nmSpinner3_6.Value;
            p = app.nmSpinner3_7.Value;      %p = 0, 1, 2...;
            E3 = sqrt(2*factorial(p)/pi/(p+factorial(abs(m))))*(1/w_z)*(sqrt(2)*r/w_z).^abs(m)...
                .*exp(-r.^2/w_z^2).*laguerre(app,p,abs(m),2*r.^2/w_z^2).*exp(-1i*m*theta).*exp(-1i*k*z)...
                .*exp(-1i*k*r.^2*z/2/(z^2+Z_R^2))*exp(-1i*(2*p+abs(m)+1)*atan(z/Z_R));
            E3=conj(E3);
            app.E_Vortex=E3;
            I3 = E3.*conj(E3);  
            I3 = I3/max(max(I3));
            X=X*100;
            Y=Y*100;
            mesh(X,Y,I3,'Parent',app.UIAxes3_1)           %三维
            colorbar(app.UIAxes3_1);
            phase = angle(E3);
            % 绘制相位图
            pcolor(X,Y,phase,'Parent',app.UIAxes3_2);
            shading(app.UIAxes3_2,'interp')
            colorbar(app.UIAxes3_2);

            lambda = app.lambda_Vortex;
            L=0.02;
            N=400;
            x = linspace(-L,L,N);     
            y = x;
            E1=app.E_Vortex;
            E1=E1/max(max(E1));
            % e0=0;
            % for i=1:400
            %     for j=1:400
            %         e0=e0+abs(E1(i,j));
            %     end
            % end
            % e0=e0/400/400;
            % e0=1;
            [X,Y] = meshgrid(x,y);
            E2 = exp(-1i*(X-200)/400*0.02/lambda*2*pi/sind(10));      %平面波
            E3=E1+E2;
            I= E3.*conj(E3);  
            I=I/max(max(I));
            X=X*100;
            Y=Y*100;
            pcolor(app.UIAxes4_2,X,Y,I)
            shading(app.UIAxes4_2, 'interp'); % 使用插值来平滑颜色
            colormap(app.UIAxes4_2,'gray')
        end

        % Value changed function: nmSpinner3_8
        function nmSpinner3_8ValueChanged(app, event)
            N= 400;
            lambda = app.nmSpinner3_1.Value*(1e-9);    %波长为632nm
            app.lambda_Vortex=lambda;
            k = 2*pi/lambda;    %波数
            w0 = app.e4mSlider3.Value*(1e-3);          %光斑尺寸
            L=0.02;
            x = linspace(-L,L,N);     
            y = x;
            [X,Y] = meshgrid(x,y);
            [theta,r] = cart2pol(X,Y);
            Z_R = pi*w0^2/lambda;      %瑞利长度
            z = 0;
            w_z = w0*sqrt(1+(z/Z_R)^2);%光束在z位置的半径
            m=app.nmSpinner3_8.Value;
            p = app.nmSpinner3_9.Value;      %p = 0, 1, 2...;
            E3 = sqrt(2*factorial(p)/pi/(p+factorial(abs(m))))*(1/w_z)*(sqrt(2)*r/w_z).^abs(m)...
                .*exp(-r.^2/w_z^2).*laguerre(app,p,abs(m),2*r.^2/w_z^2).*exp(-1i*m*theta).*exp(-1i*k*z)...
                .*exp(-1i*k*r.^2*z/2/(z^2+Z_R^2))*exp(-1i*(2*p+abs(m)+1)*atan(z/Z_R));
            E3=conj(E3);
            app.E_Vortex=E3;
            I3 = E3.*conj(E3);  
            I3 = I3/max(max(I3));
            X=X*100;
            Y=Y*100;
            mesh(X,Y,I3,'Parent',app.UIAxes3_1)           %三维
            colorbar(app.UIAxes3_1);
            phase = angle(E3);
            % 绘制相位图
            pcolor(X,Y,phase,'Parent',app.UIAxes3_2);
            shading(app.UIAxes3_2,'interp')
            colorbar(app.UIAxes3_2);

            lambda = app.lambda_Vortex;
            L=0.02;
            N=400;
            x = linspace(-L,L,N);     
            y = x;
            E1=app.E_Vortex;
            E1=E1/max(max(E1));
            % e0=0;
            % for i=1:400
            %     for j=1:400
            %         e0=e0+abs(E1(i,j));
            %     end
            % end
            % e0=e0/400/400;
            % e0=1;
            [X,Y] = meshgrid(x,y);
            for i=1:400
               for j=1:400
                    E2(i,j) = exp(-1i*(x(i)^2+y(j)^2)/lambda*2*pi/30);      %球面波
               end
            end
            E3=E1+E2;
            I= E3.*conj(E3);  
            I=I/max(max(I));
            X=X*100;
            Y=Y*100;
            pcolor(app.UIAxes4_3,X,Y,I)
            shading(app.UIAxes4_3, 'interp'); % 使用插值来平滑颜色
            colormap(app.UIAxes4_3,'gray')
        end

        % Value changed function: nmSpinner3_9
        function nmSpinner3_9ValueChanged(app, event)
            N= 400;
            lambda = app.nmSpinner3_1.Value*(1e-9);    %波长为632nm
            app.lambda_Vortex=lambda;
            k = 2*pi/lambda;    %波数
            w0 = app.e4mSlider3.Value*(1e-3);          %光斑尺寸
            L=0.02;
            x = linspace(-L,L,N);     
            y = x;
            [X,Y] = meshgrid(x,y);
            [theta,r] = cart2pol(X,Y);
            Z_R = pi*w0^2/lambda;      %瑞利长度
            z = 0;
            w_z = w0*sqrt(1+(z/Z_R)^2);%光束在z位置的半径
            m=app.nmSpinner3_8.Value;
            p = app.nmSpinner3_9.Value;      %p = 0, 1, 2...;
            E3 = sqrt(2*factorial(p)/pi/(p+factorial(abs(m))))*(1/w_z)*(sqrt(2)*r/w_z).^abs(m)...
                .*exp(-r.^2/w_z^2).*laguerre(app,p,abs(m),2*r.^2/w_z^2).*exp(-1i*m*theta).*exp(-1i*k*z)...
                .*exp(-1i*k*r.^2*z/2/(z^2+Z_R^2))*exp(-1i*(2*p+abs(m)+1)*atan(z/Z_R));
            E3=conj(E3);
            app.E_Vortex=E3;
            I3 = E3.*conj(E3);  
            I3 = I3/max(max(I3));
            X=X*100;
            Y=Y*100;
            mesh(X,Y,I3,'Parent',app.UIAxes3_1)           %三维
            colorbar(app.UIAxes3_1);
            phase = angle(E3);
            % 绘制相位图
            pcolor(X,Y,phase,'Parent',app.UIAxes3_2);
            shading(app.UIAxes3_2,'interp')
            colorbar(app.UIAxes3_2);

            lambda = app.lambda_Vortex;
            L=0.02;
            N=400;
            x = linspace(-L,L,N);     
            y = x;
            E1=app.E_Vortex;
            E1=E1/max(max(E1));
            % e0=0;
            % for i=1:400
            %     for j=1:400
            %         e0=e0+abs(E1(i,j));
            %     end
            % end
            % e0=e0/400/400;
            % e0=1;
            [X,Y] = meshgrid(x,y);
            for i=1:400
               for j=1:400
                    E2(i,j) = exp(-1i*(x(i)^2+y(j)^2)/lambda*2*pi/30);      %球面波
               end
            end
            E3=E1+E2;
            I= E3.*conj(E3);  
            I=I/max(max(I));
            X=X*100;
            Y=Y*100;
            pcolor(app.UIAxes4_3,X,Y,I)
            shading(app.UIAxes4_3, 'interp'); % 使用插值来平滑颜色
            colormap(app.UIAxes4_3,'gray')
        end

        % Value changed function: e4mSlider4_1
        function e4mSlider4_1ValueChanged(app, event)
             N= 400;
            lambda = app.nmSpinner3_1.Value*(1e-9);    %波长为632nm
            app.lambda_Vortex=lambda;
            k = 2*pi/lambda;    %波数
            w0 = app.e4mSlider3.Value*(1e-3);          %光斑尺寸
            L=0.02;
            x = linspace(-L,L,N);     
            y = x;
            [X,Y] = meshgrid(x,y);
            [theta,r] = cart2pol(X,Y);
            Z_R = pi*w0^2/lambda;      %瑞利长度
            z = 0;
            w_z = w0*sqrt(1+(z/Z_R)^2);%光束在z位置的半径
            m=app.nmSpinner3_4.Value;
            p = app.nmSpinner3_5.Value;      %p = 0, 1, 2...;
            E3 = sqrt(2*factorial(p)/pi/(p+factorial(abs(m))))*(1/w_z)*(sqrt(2)*r/w_z).^abs(m)...
                .*exp(-r.^2/w_z^2).*laguerre(app,p,abs(m),2*r.^2/w_z^2).*exp(-1i*m*theta).*exp(-1i*k*z)...
                .*exp(-1i*k*r.^2*z/2/(z^2+Z_R^2))*exp(-1i*(2*p+abs(m)+1)*atan(z/Z_R));
            E3=conj(E3);
            app.E_Vortex=E3;
            I3 = E3.*conj(E3);  
            I3 = I3/max(max(I3));
            X=X*100;
            Y=Y*100;
            mesh(X,Y,I3,'Parent',app.UIAxes3_1)           %三维
            colorbar(app.UIAxes3_1);
            phase = angle(E3);
            % 绘制相位图
            pcolor(X,Y,phase,'Parent',app.UIAxes3_2);
            shading(app.UIAxes3_2,'interp')
            colorbar(app.UIAxes3_2);

            lambda = app.lambda_Vortex;
            d = app.e4mSlider4_1.Value*1e-3;   %%双缝间距5mm
            D=30;    %%双缝距离观察屏之间的距离1m
            x1 = -d/2;
            x2 = d/2;
            L=0.02;
            N=400;
            x = linspace(-L,L,N);     
            y = x;
            for i=1:400
                for j=1:400
                    E1(i,j)=app.E_Vortex(floor(x1/0.02*200+200),i)*exp(1i*sqrt((x(j)-x1)^2+D^2)/lambda*2*pi);
                    E2(i,j)=app.E_Vortex(floor(x2/0.02*200+200),i)*exp(1i*sqrt((x(j)-x2)^2+D^2)/lambda*2*pi);
                end
            end
            E3=E1+E2;
            I= E3.*conj(E3);  
            I=I/max(max(I));
            [X,Y] = meshgrid(x,y);
            X=X*100;
            Y=Y*100;
            pcolor(app.UIAxes4,X,Y,I)
            shading(app.UIAxes4, 'interp'); % 使用插值来平滑颜色
            colormap(app.UIAxes4,'gray')
        end

        % Callback function
        function Button_17Pushed(app, event)
            if strcmp(app.TextArea.Visible,"off")
                app.TextArea.Visible="on";
            else
                app.TextArea.Visible="off";
            end
        end

        % Image clicked function: Image7
        function Image7Clicked(app, event)
            if strcmp(app.TextArea.Visible,"off")
                app.TextArea.Visible="on";
            else
                app.TextArea.Visible="off";
            end
        end

        % Image clicked function: Image7_2
        function Image7_2Clicked(app, event)
            if strcmp(app.TextArea_2.Visible,"off")
                app.TextArea_2.Visible="on";
            else
                app.TextArea_2.Visible="off";
            end
        end

        % Image clicked function: Image7_3
        function Image7_3Clicked(app, event)
            if strcmp(app.TextArea_3.Visible,"off")
                app.TextArea_3.Visible="on";
            else
                app.TextArea_3.Visible="off";
            end
        end

        % Image clicked function: Image7_4
        function Image7_4Clicked(app, event)
            if strcmp(app.TextArea_4.Visible,"off")
                app.TextArea_4.Visible="on";
            else
                app.TextArea_4.Visible="off";
            end
        end

        % Image clicked function: Image7_5
        function Image7_5Clicked(app, event)
            if strcmp(app.TextArea_5.Visible,"off")
                app.TextArea_5.Visible="on";
            else
                app.TextArea_5.Visible="off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '菜单';
            app.Image = uiimage(app.Tab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Images', '07.jpg');
            app.Button_1 = uibutton(app.Tab, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '光的衍射';
            app.Button_3 = uibutton(app.Tab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '双缝干涉';
            app.Button_4 = uibutton(app.Tab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '涡旋光的产生';
            app.Button_5 = uibutton(app.Tab, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '涡旋光的检测';
            app.Button_6 = uibutton(app.Tab, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '退出程序';
            app.Label_1 = uilabel(app.Tab);
            app.Label_1.Text = '涡旋光特性综合仿真软件';
            app.TextArea = uitextarea(app.Tab);
            app.TextArea.Visible = 'off';
            app.TextArea.Value = {'        该软件主要是想带大家更加深入地理解光的性质。首先带大家模拟夫琅禾费衍射和双缝干涉实验，帮助回忆光常见的衍射和干涉特性。'; '       在对基础知识有了回顾之后，我们先用代码模拟产生不同角向拓扑荷数和径向拓扑荷数的拉盖尔-高斯涡旋光，之后从涡旋光的双缝干涉、涡旋光与平面波的干涉以及涡旋光与球面波的干涉三个角度去对涡旋光进行检测，带大家了解在引入角向的相位分布后会对光的干涉图像分布产生的影响，通过形体各异的干涉图像来了解其对应的涡旋光参数。'; '       希望大家可以从干涉图像中体验到物理的美！'};
            app.Image7 = uiimage(app.Tab);
            app.Image7.ImageClickedFcn = createCallbackFcn(app, @Image7Clicked, true);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'Images', 'R.png');
            app.Tab_1 = uitab(app.TabGroup);
            app.Tab_1.Title = '光的衍射';
            app.UIAxes1 = uiaxes(app.Tab_1);
            title(app.UIAxes1, '小孔的夫琅禾费衍射图像')
            xlabel(app.UIAxes1, 'x坐标(\times10^{-3}m)')
            ylabel(app.UIAxes1, 'y坐标(\times10^{-3}m)')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes1.XTick = [-2 2];
            app.UIAxes1.XTickLabel = {'-2'; '2'};
            app.UIAxes1.YTick = [-2 2];
            app.UIAxes1.YTickLabel = {'-2'; '2'};
            app.Button1_1 = uibutton(app.Tab_1, 'push');
            app.Button1_1.ButtonPushedFcn = createCallbackFcn(app, @Button1_1Pushed, true);
            app.Button1_1.Text = '返回主页面';
            app.Label_2 = uilabel(app.Tab_1);
            app.Label_2.Text = '小孔半径(10e-4m)';
            app.e4mSlider = uislider(app.Tab_1);
            app.e4mSlider.Limits = [1 10];
            app.e4mSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.e4mSlider.ValueChangedFcn = createCallbackFcn(app, @e4mSliderValueChanged, true);
            app.e4mSlider.Value = 5;
            app.Button1_2 = uibutton(app.Tab_1, 'push');
            app.Button1_2.ButtonPushedFcn = createCallbackFcn(app, @Button1_2Pushed, true);
            app.Button1_2.Text = '衍射光强分布';
            app.nmSpinnerLabel = uilabel(app.Tab_1);
            app.nmSpinnerLabel.Text = '波长（nm）';
            app.nmSpinner = uispinner(app.Tab_1);
            app.nmSpinner.Step = 10;
            app.nmSpinner.ValueChangedFcn = createCallbackFcn(app, @nmSpinnerValueChanged, true);
            app.nmSpinner.Value = 500;
            app.Image2 = uiimage(app.Tab_1);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Images', '13.jpg');
            app.Image3 = uiimage(app.Tab_1);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Images', '14.jpg');
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '涡旋光';
            app.UIAxes3_2 = uiaxes(app.Tab_3);
            title(app.UIAxes3_2, '涡旋光相位分布')
            xlabel(app.UIAxes3_2, 'x坐标(\times10^{-2}m)')
            ylabel(app.UIAxes3_2, 'y坐标(\times10^{-2}m)')
            app.UIAxes3_2.XTick = [-2 2];
            app.UIAxes3_2.XTickLabel = {'-2'; '2'};
            app.UIAxes3_2.YTick = [-2 2];
            app.UIAxes3_2.YTickLabel = {'-2'; '2'};
            app.UIAxes3_1 = uiaxes(app.Tab_3);
            title(app.UIAxes3_1, '涡旋光光强分布')
            xlabel(app.UIAxes3_1, 'x坐标(\times10^{-2}m)')
            ylabel(app.UIAxes3_1, 'y坐标(\times10^{-2}m)')
            app.UIAxes3_1.XTick = [-2 2];
            app.UIAxes3_1.XTickLabel = {'-2'; '2'};
            app.UIAxes3_1.YTick = [-2 2];
            app.UIAxes3_1.YTickLabel = {'-2'; '2'};
            app.Image2_2 = uiimage(app.Tab_3);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'Images', '13.jpg');
            app.Button3_1 = uibutton(app.Tab_3, 'push');
            app.Button3_1.ButtonPushedFcn = createCallbackFcn(app, @Button3_1Pushed, true);
            app.Button3_1.Text = '返回主页面';
            app.Button3_2 = uibutton(app.Tab_3, 'push');
            app.Button3_2.ButtonPushedFcn = createCallbackFcn(app, @Button3_2Pushed, true);
            app.Button3_2.Text = '按当前页面参数重置涡旋光';
            app.nmSpinner_2Label_2 = uilabel(app.Tab_3);
            app.nmSpinner_2Label_2.Text = '波长（nm）';
            app.nmSpinner3_1 = uispinner(app.Tab_3);
            app.nmSpinner3_1.Step = 10;
            app.nmSpinner3_1.ValueChangedFcn = createCallbackFcn(app, @nmSpinner3_1ValueChanged, true);
            app.nmSpinner3_1.Value = 500;
            app.nmSpinner_2Label_3 = uilabel(app.Tab_3);
            app.nmSpinner_2Label_3.Text = '角向拓扑荷数';
            app.nmSpinner3_2 = uispinner(app.Tab_3);
            app.nmSpinner3_2.ValueChangedFcn = createCallbackFcn(app, @nmSpinner3_2ValueChanged, true);
            app.nmSpinner3_2.Value = 1;
            app.e4mLabel_2 = uilabel(app.Tab_3);
            app.e4mLabel_2.Text = '光斑尺寸(mm)';
            app.e4mSlider3 = uislider(app.Tab_3);
            app.e4mSlider3.Limits = [1 10];
            app.e4mSlider3.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.e4mSlider3.ValueChangedFcn = createCallbackFcn(app, @e4mSlider3ValueChanged, true);
            app.e4mSlider3.Value = 5;
            app.nmSpinner_2Label_4 = uilabel(app.Tab_3);
            app.nmSpinner_2Label_4.Text = '径向拓扑荷数';
            app.nmSpinner3_3 = uispinner(app.Tab_3);
            app.nmSpinner3_3.Limits = [0 Inf];
            app.nmSpinner3_3.ValueChangedFcn = createCallbackFcn(app, @nmSpinner3_3ValueChanged, true);
            app.Button3_3 = uibutton(app.Tab_3, 'push');
            app.Button3_3.ButtonPushedFcn = createCallbackFcn(app, @Button3_3Pushed, true);
            app.Button3_3.Text = '跳转到检测界面';
            app.Image3_2 = uiimage(app.Tab_3);
            app.Image3_2.ImageSource = fullfile(pathToMLAPP, 'Images', '14.jpg');
            app.Image7_2 = uiimage(app.Tab_3);
            app.Image7_2.ImageClickedFcn = createCallbackFcn(app, @Image7_2Clicked, true);
            app.Image7_2.ImageSource = fullfile(pathToMLAPP, 'Images', 'R.png');
            app.TextArea_2 = uitextarea(app.Tab_3);
            app.TextArea_2.Visible = 'off';
            app.TextArea_2.Value = {'       涡旋光相较于一般的光有着角向的相位和径向的相位突变。具体与之关联的是涡旋光的角向拓扑荷数和径向拓扑荷数。如果光子的角向拓扑荷数为m，那么它旋转2pi的角度，相位变化2mpi，相位和角度呈现线性关系。如果径向拓扑荷数是l，那么就有l个径向相位突变位置和l+1个极大值峰。'};
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '双缝干涉';
            app.UIAxes2 = uiaxes(app.Tab_2);
            title(app.UIAxes2, '双缝干涉')
            xlabel(app.UIAxes2, 'x坐标(\times10^{-4}m)')
            ylabel(app.UIAxes2, 'y坐标(\times10^{-4}m)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [-1 1];
            app.UIAxes2.XTickLabel = {'-1'};
            app.UIAxes2.YTick = [-1 1];
            app.UIAxes2.YTickLabel = {'-1'};
            app.Button2_1 = uibutton(app.Tab_2, 'push');
            app.Button2_1.ButtonPushedFcn = createCallbackFcn(app, @Button2_1Pushed, true);
            app.Button2_1.Text = '返回主页面';
            app.Button2_2 = uibutton(app.Tab_2, 'push');
            app.Button2_2.ButtonPushedFcn = createCallbackFcn(app, @Button2_2Pushed, true);
            app.Button2_2.Text = '双缝干涉光强分布';
            app.e4mLabel = uilabel(app.Tab_2);
            app.e4mLabel.Text = '狭缝间距(10e-4m)';
            app.e4mSlider2 = uislider(app.Tab_2);
            app.e4mSlider2.Limits = [1 10];
            app.e4mSlider2.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.e4mSlider2.ValueChangedFcn = createCallbackFcn(app, @e4mSlider2ValueChanged, true);
            app.e4mSlider2.Value = 5;
            app.nmSpinner_2Label = uilabel(app.Tab_2);
            app.nmSpinner_2Label.Text = '波长（nm）';
            app.nmSpinner2 = uispinner(app.Tab_2);
            app.nmSpinner2.Step = 10;
            app.nmSpinner2.ValueChangedFcn = createCallbackFcn(app, @nmSpinner2ValueChanged, true);
            app.nmSpinner2.Value = 500;
            app.Image2_3 = uiimage(app.Tab_2);
            app.Image2_3.ImageSource = fullfile(pathToMLAPP, 'Images', '13.jpg');
            app.Image3_3 = uiimage(app.Tab_2);
            app.Image3_3.ImageSource = fullfile(pathToMLAPP, 'Images', '14.jpg');
            app.Tab_5 = uitab(app.TabGroup);
            app.Tab_5.Title = '涡旋光的检测';
            app.Image_2 = uiimage(app.Tab_5);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Images', '07.jpg');
            app.Button_7 = uibutton(app.Tab_5, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '涡旋光的双缝干涉';
            app.Button_8 = uibutton(app.Tab_5, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '涡旋光与平面波干涉';
            app.Button_9 = uibutton(app.Tab_5, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '涡旋光与球面波干涉';
            app.Button_10 = uibutton(app.Tab_5, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '返回主页面';
            app.Label_3 = uilabel(app.Tab_5);
            app.Label_3.Text = '涡旋光的检测';
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '双缝干涉';
            app.UIAxes4 = uiaxes(app.Tab_4);
            title(app.UIAxes4, '涡旋光的双缝干涉图像')
            xlabel(app.UIAxes4, 'x坐标(\times10^{-2}m)')
            ylabel(app.UIAxes4, 'y坐标(\times10^{-2}m)')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.Button4_1 = uibutton(app.Tab_4, 'push');
            app.Button4_1.ButtonPushedFcn = createCallbackFcn(app, @Button4_1Pushed, true);
            app.Button4_1.Text = '返回主页面';
            app.e4mSlider4_1 = uislider(app.Tab_4);
            app.e4mSlider4_1.Limits = [1 10];
            app.e4mSlider4_1.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.e4mSlider4_1.ValueChangedFcn = createCallbackFcn(app, @e4mSlider4_1ValueChanged, true);
            app.e4mSlider4_1.Value = 5;
            app.e4mLabel_3 = uilabel(app.Tab_4);
            app.e4mLabel_3.Text = '狭缝间距(10e-3m)';
            app.Button2_3 = uibutton(app.Tab_4, 'push');
            app.Button2_3.ButtonPushedFcn = createCallbackFcn(app, @Button2_3Pushed, true);
            app.Button2_3.Text = '干涉光强分布';
            app.Button_12 = uibutton(app.Tab_4, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '返回上级';
            app.Button_14 = uibutton(app.Tab_4, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '跳转到涡旋光';
            app.Image2_4 = uiimage(app.Tab_4);
            app.Image2_4.ImageSource = fullfile(pathToMLAPP, 'Images', '13.jpg');
            app.Image3_4 = uiimage(app.Tab_4);
            app.Image3_4.ImageSource = fullfile(pathToMLAPP, 'Images', '14.jpg');
            app.Button3_4 = uibutton(app.Tab_4, 'push');
            app.Button3_4.ButtonPushedFcn = createCallbackFcn(app, @Button3_4Pushed, true);
            app.Button3_4.Text = '按当前页面参数重置涡旋光';
            app.nmSpinner_2Label_5 = uilabel(app.Tab_4);
            app.nmSpinner_2Label_5.Text = '角向拓扑荷数';
            app.nmSpinner3_4 = uispinner(app.Tab_4);
            app.nmSpinner3_4.ValueChangedFcn = createCallbackFcn(app, @nmSpinner3_4ValueChanged, true);
            app.nmSpinner_2Label_6 = uilabel(app.Tab_4);
            app.nmSpinner_2Label_6.Text = '径向拓扑荷数';
            app.nmSpinner3_5 = uispinner(app.Tab_4);
            app.nmSpinner3_5.Limits = [0 Inf];
            app.nmSpinner3_5.ValueChangedFcn = createCallbackFcn(app, @nmSpinner3_5ValueChanged, true);
            app.Image7_3 = uiimage(app.Tab_4);
            app.Image7_3.ImageClickedFcn = createCallbackFcn(app, @Image7_3Clicked, true);
            app.Image7_3.ImageSource = fullfile(pathToMLAPP, 'Images', 'R.png');
            app.TextArea_3 = uitextarea(app.Tab_4);
            app.TextArea_3.Visible = 'off';
            app.TextArea_3.Value = {'        涡旋光由于角向的相位分布，经过双缝干涉后的条纹会发生扭曲。角向拓扑荷数越大，双缝干涉条纹的扭曲程度越大。如果存在径向拓扑荷数，会有多个光强极大值位置，条纹就会出现分段，径向拓扑荷数越大分段越多。如果角向拓扑荷数和径向拓扑荷数都为0，那么就是正常的平直条纹。'};
            app.Tab_6 = uitab(app.TabGroup);
            app.Tab_6.Title = '平面波干涉';
            app.UIAxes4_2 = uiaxes(app.Tab_6);
            title(app.UIAxes4_2, '涡旋光与平面波干涉图像')
            xlabel(app.UIAxes4_2, 'x坐标(\times10^{-2}m)')
            ylabel(app.UIAxes4_2, 'y坐标(\times10^{-2}m)')
            zlabel(app.UIAxes4_2, 'Z')
            app.UIAxes4_2.XTick = [-2 2];
            app.UIAxes4_2.XTickLabel = {'-2'; '2'};
            app.UIAxes4_2.YTick = [-2 2];
            app.UIAxes4_2.YTickLabel = {'-2'; '2'};
            app.Button1_3 = uibutton(app.Tab_6, 'push');
            app.Button1_3.ButtonPushedFcn = createCallbackFcn(app, @Button1_3Pushed, true);
            app.Button1_3.Text = '返回主页面';
            app.Button2_5 = uibutton(app.Tab_6, 'push');
            app.Button2_5.ButtonPushedFcn = createCallbackFcn(app, @Button2_5Pushed, true);
            app.Button2_5.Text = '干涉光强分布';
            app.Button_11 = uibutton(app.Tab_6, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '返回上级';
            app.Button_15 = uibutton(app.Tab_6, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '跳转到涡旋光';
            app.Image2_5 = uiimage(app.Tab_6);
            app.Image2_5.ImageSource = fullfile(pathToMLAPP, 'Images', '13.jpg');
            app.Image3_5 = uiimage(app.Tab_6);
            app.Image3_5.ImageSource = fullfile(pathToMLAPP, 'Images', '14.jpg');
            app.Button3_5 = uibutton(app.Tab_6, 'push');
            app.Button3_5.ButtonPushedFcn = createCallbackFcn(app, @Button3_5Pushed, true);
            app.Button3_5.Text = '按当前页面参数重置涡旋光';
            app.nmSpinner_2Label_7 = uilabel(app.Tab_6);
            app.nmSpinner_2Label_7.Text = '角向拓扑荷数';
            app.nmSpinner3_6 = uispinner(app.Tab_6);
            app.nmSpinner3_6.ValueChangedFcn = createCallbackFcn(app, @nmSpinner3_6ValueChanged, true);
            app.nmSpinner_2Label_8 = uilabel(app.Tab_6);
            app.nmSpinner_2Label_8.Text = '径向拓扑荷数';
            app.nmSpinner3_7 = uispinner(app.Tab_6);
            app.nmSpinner3_7.Limits = [0 Inf];
            app.nmSpinner3_7.ValueChangedFcn = createCallbackFcn(app, @nmSpinner3_7ValueChanged, true);
            app.Image7_4 = uiimage(app.Tab_6);
            app.Image7_4.ImageClickedFcn = createCallbackFcn(app, @Image7_4Clicked, true);
            app.Image7_4.ImageSource = fullfile(pathToMLAPP, 'Images', 'R.png');
            app.TextArea_4 = uitextarea(app.Tab_6);
            app.TextArea_4.Visible = 'off';
            app.TextArea_4.Value = {'        涡旋光由于角向的相位分布，在与一斜入射的平面波发生干涉后，干涉条纹会发生分叉的现象。角向拓扑荷数越大，干涉条纹的分叉数越多。如果存在径向拓扑荷数，会有多个光强极大值位置，干涉条纹就会有多个亮环。如果角向拓扑荷数和径向拓扑荷数都为0，那么就是正常的平直条纹。'};
            app.Tab_7 = uitab(app.TabGroup);
            app.Tab_7.Title = '球面波干涉';
            app.UIAxes4_3 = uiaxes(app.Tab_7);
            title(app.UIAxes4_3, '涡旋光与球面波干涉图像')
            xlabel(app.UIAxes4_3, 'x坐标(\times10^{-2}m)')
            ylabel(app.UIAxes4_3, 'y坐标(\times10^{-2}m)')
            zlabel(app.UIAxes4_3, 'Z')
            app.UIAxes4_3.XTick = [-2 2];
            app.UIAxes4_3.XTickLabel = {'-2'; '2'};
            app.UIAxes4_3.YTick = [-2 2];
            app.UIAxes4_3.YTickLabel = {'-2'; '2'};
            app.Button1_4 = uibutton(app.Tab_7, 'push');
            app.Button1_4.ButtonPushedFcn = createCallbackFcn(app, @Button1_4Pushed, true);
            app.Button1_4.Text = '返回主页面';
            app.Button2_4 = uibutton(app.Tab_7, 'push');
            app.Button2_4.ButtonPushedFcn = createCallbackFcn(app, @Button2_4Pushed, true);
            app.Button2_4.Text = '干涉光强分布';
            app.Button_13 = uibutton(app.Tab_7, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '返回上级';
            app.Button_16 = uibutton(app.Tab_7, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '跳转到涡旋光';
            app.Image2_6 = uiimage(app.Tab_7);
            app.Image2_6.ImageSource = fullfile(pathToMLAPP, 'Images', '13.jpg');
            app.Image3_6 = uiimage(app.Tab_7);
            app.Image3_6.ImageSource = fullfile(pathToMLAPP, 'Images', '14.jpg');
            app.Button3_6 = uibutton(app.Tab_7, 'push');
            app.Button3_6.ButtonPushedFcn = createCallbackFcn(app, @Button3_6Pushed, true);
            app.Button3_6.Text = '按当前页面参数重置涡旋光';
            app.nmSpinner_2Label_9 = uilabel(app.Tab_7);
            app.nmSpinner_2Label_9.Text = '角向拓扑荷数';
            app.nmSpinner3_8 = uispinner(app.Tab_7);
            app.nmSpinner3_8.ValueChangedFcn = createCallbackFcn(app, @nmSpinner3_8ValueChanged, true);
            app.nmSpinner_2Label_10 = uilabel(app.Tab_7);
            app.nmSpinner_2Label_10.Text = '径向拓扑荷数';
            app.nmSpinner3_9 = uispinner(app.Tab_7);
            app.nmSpinner3_9.Limits = [0 Inf];
            app.nmSpinner3_9.ValueChangedFcn = createCallbackFcn(app, @nmSpinner3_9ValueChanged, true);
            app.Image7_5 = uiimage(app.Tab_7);
            app.Image7_5.ImageClickedFcn = createCallbackFcn(app, @Image7_5Clicked, true);
            app.Image7_5.ImageSource = fullfile(pathToMLAPP, 'Images', 'R.png');
            app.TextArea_5 = uitextarea(app.Tab_7);
            app.TextArea_5.Visible = 'off';
            app.TextArea_5.Value = {'        涡旋光由于角向的相位分布，在与一球面波发生干涉后，会出现类似太极图一样的分支。角向拓扑荷数越大，干涉条纹的分支数越多。如果存在径向拓扑荷数，会有多个光强极大值位置，干涉条纹就会有多个亮环。如果角向拓扑荷数和径向拓扑荷数都为0，那么就是正常的圆环条纹。'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = optic

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end